classdef Answer02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Button                matlab.ui.control.Button           % Select I...
        LabelNumericEditField matlab.ui.control.Label            % Rank
        NumericEditField      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                 matlab.ui.control.Label            % Label
        Button2               matlab.ui.control.Button           % Compress
        Button3               matlab.ui.control.Button           % Show Result
        Button4               matlab.ui.control.Button           % Save Image
        Label2                matlab.ui.control.Label            % Label
        Label3                matlab.ui.control.Label            % Label
        Button5               matlab.ui.control.Button           % Show Image
        Button6               matlab.ui.control.Button           % CR Plot
        Button7               matlab.ui.control.Button           % MSE Plot
    end


    properties (Access = public)
        inImage % Description
        outImage
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumericEditField.Enable = 'off';
            app.Button2.Enable = 'off';
            app.Button3.Enable = 'off';
            app.Button4.Enable = 'off';
            app.Button5.Enable = 'off';
            app.Button6.Enable = 'off';
            app.Button7.Enable = 'off';
            app.Label2.Text = 'Compression Rate = NA!';
            app.Label3.Text = 'Mean Squared Error = NA!';
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            [filename, pathname] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png;',...
                'Images (*.jpg,*.jpeg,*.png)';}, ...
                'Choose an image');
            address = sprintf('%s%s', pathname, filename);
            if exist(address, 'file') == 2
                app.Label.Text = filename;
                
                app.inImage = im2double(imread(address));
                
                app.NumericEditField.Enable = 'on';
                upperLim = min(size(app.inImage, 1), size(app.inImage, 2));
                app.NumericEditField.Limits = [1 upperLim];
                app.Button2.Enable = 'on';
                app.Button5.Enable = 'on';
                app.Button6.Enable = 'on';
                app.Button7.Enable = 'on';
                app.Label2.Text = sprintf('Compression Rate = %f', ...
                    cr(size(app.inImage, 1), ...
                    size(app.inImage, 2), ...
                    app.NumericEditField.Value));
                app.Label3.Text = 'Mean Squared Error = NA!';
            end
        end

        % NumericEditField value changed function
        function NumericEditFieldValueChanged(app)
            value = app.NumericEditField.Value;
            app.Button3.Enable = 'off';
            app.Button4.Enable = 'off';
            app.Label2.Text = sprintf('Compression Rate = %f', ...
                cr(size(app.inImage, 1), ...
                size(app.inImage, 2), ...
                app.NumericEditField.Value));
                app.Label3.Text = 'Mean Squared Error = NA!';
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            app.outImage = ImageCompressor(app.inImage, ...
                app.NumericEditField.Value);
            app.Button3.Enable = 'on';
            app.Button4.Enable = 'on';
            app.Label3.Text = sprintf('Mean Squared Error = %f', ...
                mse2d(app.inImage, app.outImage));
        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)
            subplot(1, 2, 1);
            imshow(app.inImage);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(app.outImage);
            title(sprintf('Compressed Image (Rank: %d)', ...
                app.NumericEditField.Value));
        end

        % Button5 button pushed function
        function Button5ButtonPushed(app)
            imshow(app.inImage)
            title(sprintf('Original Image (%s)', app.Label.Text));
        end

        % Button4 button pushed function
        function Button4ButtonPushed(app)
            [fileName, pathName] = uiputfile();
            imwrite(app.outImage, ...
                sprintf('%s%s', pathName, fileName));
        end

        % Button6 button pushed function
        function Button6ButtonPushed(app)
            % CR Plot
            upperLim = min(size(app.inImage, 1), size(app.inImage, 2));
            xMax = floor(log2(upperLim)) - 2;
            plot(2 .^ (2:0.5:xMax), ...
                cr(size(app.inImage, 1), size(app.inImage, 2), 2 .^ (2:0.5:xMax)), ...
                '-o', 'MarkerFaceColor', 'red');
            xlabel('Rank');
            ylabel('Compression Rate');
        end

        % Button7 button pushed function
        function Button7ButtonPushed(app)
            % MSE Plot
            upperLim = min(size(app.inImage, 1), size(app.inImage, 2));
            xMax = floor(log2(upperLim)) - 2;
            x = 2 .^ (2:0.5:xMax);
            y = zeros(1, size(x, 2));
            for i = 1:size(x, 2)
                y(i) = mse2d(app.inImage, ...
                    ImageCompressor(app.inImage, x(i)));
            end
            plot(x, ...
                y, ...
                '-o', ...
                'MarkerFaceColor', 'red');
            xlabel('Rank');
            ylabel('Mean Squared Error');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Select Image';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Rank';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Compress';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed);
            app.Button3.Text = 'Show Result';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed);
            app.Button4.Text = 'Save Image';
            app.Label2 = uilabel(app.UIFigure);
            app.Label3 = uilabel(app.UIFigure);
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5ButtonPushed);
            app.Button5.Text = 'Show Image';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6ButtonPushed);
            app.Button6.Text = 'CR Plot';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7ButtonPushed);
            app.Button7.Text = 'MSE Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Answer02()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end